classdef Queries < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        learning                matlab.ui.Figure
        Panel                   matlab.ui.container.Panel
        GridLayout              matlab.ui.container.GridLayout
        GridLayout4             matlab.ui.container.GridLayout
        GridLayout5             matlab.ui.container.GridLayout
        GridLayout6             matlab.ui.container.GridLayout
        FechafinLabel           matlab.ui.control.Label
        GridLayout9             matlab.ui.container.GridLayout
        FechaInicioLabel        matlab.ui.control.Label
        DatePicker_2            matlab.ui.control.DatePicker
        GridLayout10            matlab.ui.container.GridLayout
        CochesCheckBox          matlab.ui.control.CheckBox
        CamionesCheckBox        matlab.ui.control.CheckBox
        MotosCheckBox           matlab.ui.control.CheckBox
        CheckBox2               matlab.ui.control.CheckBox
        CheckBox3               matlab.ui.control.CheckBox
        UIAxes                  matlab.ui.control.UIAxes
        UITable                 matlab.ui.control.Table
        CheckBox                matlab.ui.control.CheckBox
        DatePicker              matlab.ui.control.DatePicker
        GridLayout3             matlab.ui.container.GridLayout
        ThingSpeakQueriesLabel  matlab.ui.control.Label
        Image5                  matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            drawnow;
            app.learning.WindowState = 'maximized';
        end

        % Callback function
        function ImageClicked(app, event)
            close(app.learning);
        end

        % Callback function
        function Image8Clicked(app, event)
            close(app.learning);
            SmartCities
        end

        % Callback function
        function RetrainingImageClicked(app, event)
            Entrenamiento
        end

        % Callback function
        function UpdateTrainingDataImageClicked(app, event)
            winopen(fullfile(pwd,"Vehiculos"));
        end

        % Callback function
        function ImageResizerImageClicked(app, event)
            close all, clear all;
            resize_image_folder = fullfile(pwd);
            resize_image_destiny_folder = fullfile(pwd,"Recortes");  
            [filename,user_canceled] = imgetfile('InitialPath',resize_image_folder,'MultiSelect',true);
            if ~user_canceled
                [nRow, nCol] = size(filename);
                for row=1:nRow
                    for col=1:nCol
                        I = imread(filename{row, col});
                        I = imresize(I, [227 227]);
                        [filepath,name,ext] = fileparts(filename{row, col});
                        imwrite(I,fullfile(resize_image_destiny_folder, strcat(name, ext)));
                    end
                end
                winopen(resize_image_destiny_folder);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.learning = uifigure('Visible', 'off');
            app.learning.Name = 'Smart Cities';
            app.Panel = uipanel(app.learning);
            app.GridLayout = uigridlayout(app.Panel);
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.ThingSpeakQueriesLabel = uilabel(app.GridLayout3);
            app.ThingSpeakQueriesLabel.Text = 'ThingSpeak Queries';
            app.Image5 = uiimage(app.GridLayout3);
            app.Image5.ImageSource = 'google-big-query-logo.png';
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.FechafinLabel = uilabel(app.GridLayout6);
            app.FechafinLabel.Text = 'Fecha fin:';
            app.DatePicker = uidatepicker(app.GridLayout6);
            app.GridLayout9 = uigridlayout(app.GridLayout5);
            app.FechaInicioLabel = uilabel(app.GridLayout9);
            app.FechaInicioLabel.Text = 'Fecha Inicio:';
            app.DatePicker_2 = uidatepicker(app.GridLayout9);
            app.GridLayout10 = uigridlayout(app.GridLayout4);
            app.CochesCheckBox = uicheckbox(app.GridLayout10);
            app.CochesCheckBox.Text = 'Coches';
            app.CamionesCheckBox = uicheckbox(app.GridLayout10);
            app.CamionesCheckBox.Text = 'Camiones';
            app.MotosCheckBox = uicheckbox(app.GridLayout10);
            app.MotosCheckBox.Text = 'Motos';
            app.CheckBox = uicheckbox(app.GridLayout10);
            app.CheckBox2 = uicheckbox(app.GridLayout10);
            app.CheckBox2.Text = 'Check Box2';
            app.CheckBox3 = uicheckbox(app.GridLayout10);
            app.CheckBox3.Text = 'Check Box3';
            app.UIAxes = uiaxes(app.GridLayout4);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UITable = uitable(app.GridLayout4);
            app.UITable.ColumnName = {'Coches'; 'Camiones'; 'Total'; 'Column 4'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.learning.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Queries

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.learning)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.learning)
        end
    end
end