classdef Retraining < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        retraining                      matlab.ui.Figure
        Panel                           matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        GridLayout11                    matlab.ui.container.GridLayout
        backImage                       matlab.ui.control.Image
        CODE_NAME                       matlab.ui.control.Image
        TabGroup                        matlab.ui.container.TabGroup
        LastFullyConnectedLayerTab      matlab.ui.container.Tab
        GridLayout14                    matlab.ui.container.GridLayout
        GridLayout15                    matlab.ui.container.GridLayout
        RetrainingLabel                 matlab.ui.control.Label
        Image5                          matlab.ui.control.Image
        GridLayout16                    matlab.ui.container.GridLayout
        WeightLearnRateFactorLabel      matlab.ui.control.Label
        BiasLearnRateFactorLabel        matlab.ui.control.Label
        GridLayout17                    matlab.ui.container.GridLayout
        WeightLearnRateFactorEditField  matlab.ui.control.NumericEditField
        GridLayout18                    matlab.ui.container.GridLayout
        BiasLearnRateFactorEditField    matlab.ui.control.NumericEditField
        SelectyourconfigurationoptionsLabel  matlab.ui.control.Label
        TrainingOptionsTab              matlab.ui.container.Tab
        GridLayout13                    matlab.ui.container.GridLayout
        SolverMethodLabel               matlab.ui.control.Label
        MiniBatchSizeLabel              matlab.ui.control.Label
        MaxEpochsLabel                  matlab.ui.control.Label
        InitialLearnRateLabel           matlab.ui.control.Label
        ValidationFrequencyLabel        matlab.ui.control.Label
        ValidationPatienceLabel         matlab.ui.control.Label
        MiniBatchSizeEditField          matlab.ui.control.NumericEditField
        MaxEpochsEditField              matlab.ui.control.NumericEditField
        InitialLearnRateEditField       matlab.ui.control.NumericEditField
        ValidationFrequencyEditField    matlab.ui.control.NumericEditField
        ValidationPatienceEditField     matlab.ui.control.NumericEditField
        SolverMethodDropDown            matlab.ui.control.DropDown
    end

    
    methods (Access = public)
        function update(app, context)
            event = getEvent(context);
            switch event
            case Events.GUI_RETRAINING
                app.retraining.Visible = true;
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.retraining.Visible = false;
            drawnow;
            app.retraining.WindowState = 'maximized';          
        end

        % Image clicked function: backImage
        function backImageClicked(app, event)
            app.retraining.Visible = false;
            Controler.getInstance().execute(Context(Events.GUI_LEARNING, NaN));
        end

        % Image clicked function: CODE_NAME
        function retrainingImageClicked(app, event)
           tConfigData = TConfigData(app.WeightLearnRateFactorEditField.Value,...
           app.BiasLearnRateFactorEditField.Value,...
           app.SolverMethodDropDown.Value,...
           app.MiniBatchSizeEditField.Value,...
           app.MaxEpochsEditField.Value,...
           app.InitialLearnRateEditField.Value,...
           app.ValidationFrequencyEditField.Value,...
           app.ValidationPatienceEditField.Value)
       
           Controler.getInstance().execute(Context(Events.EXECUTE_LEARNING, tConfigData));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.retraining = uifigure('Visible', 'off');
            app.retraining.Name = 'Smart Cities';
            app.Panel = uipanel(app.retraining);
            app.GridLayout = uigridlayout(app.Panel);
            app.GridLayout11 = uigridlayout(app.GridLayout);
            app.backImage = uiimage(app.GridLayout11);
            app.backImage.ImageClickedFcn = createCallbackFcn(app, @backImageClicked, true);
            app.backImage.ImageSource = 'reply.png';
            app.CODE_NAME = uiimage(app.GridLayout11);
            app.CODE_NAME.ImageClickedFcn = createCallbackFcn(app, @retrainingImageClicked, true);
            app.CODE_NAME.ImageSource = 'learning.png';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.LastFullyConnectedLayerTab = uitab(app.TabGroup);
            app.LastFullyConnectedLayerTab.Title = 'Last Fully Connected Layer';
            app.GridLayout14 = uigridlayout(app.LastFullyConnectedLayerTab);
            app.GridLayout15 = uigridlayout(app.GridLayout14);
            app.RetrainingLabel = uilabel(app.GridLayout15);
            app.RetrainingLabel.Text = 'Retraining';
            app.Image5 = uiimage(app.GridLayout15);
            app.Image5.ImageSource = '171-1712673_tuercas-png-logo-de-tuercas-png.png';
            app.GridLayout16 = uigridlayout(app.GridLayout14);
            app.WeightLearnRateFactorLabel = uilabel(app.GridLayout16);
            app.WeightLearnRateFactorLabel.Text = 'Weight Learn Rate Factor:';
            app.BiasLearnRateFactorLabel = uilabel(app.GridLayout16);
            app.BiasLearnRateFactorLabel.Text = 'Bias Learn RateFactor:';
            app.GridLayout17 = uigridlayout(app.GridLayout16);
            app.WeightLearnRateFactorEditField = uieditfield(app.GridLayout17, 'numeric');
            app.WeightLearnRateFactorEditField.LowerLimitInclusive = 'off';
            app.WeightLearnRateFactorEditField.Limits = [0 Inf];
            app.WeightLearnRateFactorEditField.Value = 20;
            app.GridLayout18 = uigridlayout(app.GridLayout16);
            app.BiasLearnRateFactorEditField = uieditfield(app.GridLayout18, 'numeric');
            app.BiasLearnRateFactorEditField.LowerLimitInclusive = 'off';
            app.BiasLearnRateFactorEditField.Limits = [0 Inf];
            app.BiasLearnRateFactorEditField.Value = 20;
            app.SelectyourconfigurationoptionsLabel = uilabel(app.GridLayout14);
            app.SelectyourconfigurationoptionsLabel.Text = 'Select your configuration options:';
            app.TrainingOptionsTab = uitab(app.TabGroup);
            app.TrainingOptionsTab.Title = 'Training Options';
            app.GridLayout13 = uigridlayout(app.TrainingOptionsTab);
            app.SolverMethodLabel = uilabel(app.GridLayout13);
            app.SolverMethodLabel.Text = 'Solver Method:';
            app.MiniBatchSizeLabel = uilabel(app.GridLayout13);
            app.MiniBatchSizeLabel.Text = 'Mini Batch Size:';
            app.MaxEpochsLabel = uilabel(app.GridLayout13);
            app.MaxEpochsLabel.Text = 'Max Epochs:';
            app.InitialLearnRateLabel = uilabel(app.GridLayout13);
            app.InitialLearnRateLabel.Text = 'Initial Learn Rate:';
            app.ValidationFrequencyLabel = uilabel(app.GridLayout13);
            app.ValidationFrequencyLabel.Text = 'Validation Frequency:';
            app.ValidationPatienceLabel = uilabel(app.GridLayout13);
            app.ValidationPatienceLabel.Text = 'Validation Patience:';
            app.MiniBatchSizeEditField = uieditfield(app.GridLayout13, 'numeric');
            app.MiniBatchSizeEditField.LowerLimitInclusive = 'off';
            app.MiniBatchSizeEditField.Limits = [0 Inf];
            app.MiniBatchSizeEditField.Value = 10;
            app.MaxEpochsEditField = uieditfield(app.GridLayout13, 'numeric');
            app.MaxEpochsEditField.LowerLimitInclusive = 'off';
            app.MaxEpochsEditField.Limits = [0 Inf];
            app.MaxEpochsEditField.Value = 60;
            app.InitialLearnRateEditField = uieditfield(app.GridLayout13, 'numeric');
            app.InitialLearnRateEditField.LowerLimitInclusive = 'off';
            app.InitialLearnRateEditField.Limits = [0 Inf];
            app.InitialLearnRateEditField.Value = 0.0001;
            app.ValidationFrequencyEditField = uieditfield(app.GridLayout13, 'numeric');
            app.ValidationFrequencyEditField.LowerLimitInclusive = 'off';
            app.ValidationFrequencyEditField.Limits = [0 Inf];
            app.ValidationFrequencyEditField.Value = 3;
            app.ValidationPatienceEditField = uieditfield(app.GridLayout13, 'numeric');
            app.ValidationPatienceEditField.LowerLimitInclusive = 'off';
            app.ValidationPatienceEditField.Limits = [0 Inf];
            app.ValidationPatienceEditField.Value = Inf;
            app.SolverMethodDropDown = uidropdown(app.GridLayout13);
            app.SolverMethodDropDown.Items = {'sgdm', 'rmsprop', 'adam'};
            app.SolverMethodDropDown.Value = 'sgdm';
            % Show the figure after all components are created
            app.retraining.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Retraining

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.retraining)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.retraining)
        end
    end
end